classdef fitGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        filename                 matlab.ui.control.EditField
        fitButton                matlab.ui.control.Button
        filepath                 matlab.ui.control.EditField
        selectdataButton         matlab.ui.control.Button
        reactionorderstep        matlab.ui.control.NumericEditField
        reactionordermax         matlab.ui.control.NumericEditField
        reactionordermin         matlab.ui.control.NumericEditField
        rateconstantstep         matlab.ui.control.NumericEditField
        rateconstantmax          matlab.ui.control.NumericEditField
        rateconstantmin          matlab.ui.control.NumericEditField
        ultimateconversionstep   matlab.ui.control.NumericEditField
        ultimateconversionmax    matlab.ui.control.NumericEditField
        ultimateconversionmin    matlab.ui.control.NumericEditField
        reactionorderLabel       matlab.ui.control.Label
        rateconstantLabel        matlab.ui.control.Label
        ultimateconversionLabel  matlab.ui.control.Label
        DropDown_m               matlab.ui.control.DropDown
        DropDown_k               matlab.ui.control.DropDown
        DropDown_au              matlab.ui.control.DropDown
        nsteps                   matlab.ui.control.Label
        maxLabel                 matlab.ui.control.Label
        minLabel                 matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: selectdataButton
        function selectdataButtonPushed(app, event)
            [filename1,path1] = uigetfile('*.mat');
            app.filepath.Value = path1;
            app.filename.Value = filename1;
        end

        % Button pushed function: fitButton
        function fitButtonPushed(app, event)
            load([app.filepath.Value app.filename.Value],'mastercurve');
            
            maxau = app.ultimateconversionmax.Value;
            minau = app.ultimateconversionmin.Value;
            stepau = app.ultimateconversionstep.Value;
            maxk = app.rateconstantmax.Value;
            mink = app.rateconstantmin.Value;
            stepk = app.rateconstantstep.Value;
            maxm = app.reactionordermax.Value;
            minm = app.reactionordermin.Value;
            stepm = app.reactionorderstep.Value;
            
            switch app.DropDown_au.Value
                case 'linear'                    
                    aus = minau:stepau:maxau;
                case 'log'
                    aus = minau.^(0:stepau:log10(maxau/minau));
            end
            
            switch app.DropDown_k.Value
                case 'linear'                    
                    ks = mink:stepk:maxk;
                case 'log'
                    ks = mink.^(0:stepk:log10(maxk/mink));
            end
            
            switch app.DropDown_m.Value
                case 'linear'                    
                    ms = minm:stepm:maxm;
                case 'log'
                    ms = minm.^(0:stepm:log10(maxm/minm));
            end
            
            fitparams = stepfittingreactionkinetics_jt(mastercurve.Iwt,...
                mastercurve.conversion,aus,ks,ms,1);
            
            savename = [app.filename.Value(1:end-4) 'fitting'];
            assignin('base',savename,fitparams);
            
            title(sprintf(['sample: ' mastercurve.sample '\nw = ' num2str(mastercurve.w)]));
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.minLabel = uilabel(app.UIFigure);
            app.minLabel.Text = 'min';
            app.maxLabel = uilabel(app.UIFigure);
            app.maxLabel.Text = 'max';
            app.nsteps = uilabel(app.UIFigure);
            app.nsteps.Text = 'step size';
            app.DropDown_au = uidropdown(app.UIFigure);
            app.DropDown_au.Items = {'linear', 'log'};
            app.DropDown_au.Value = 'linear';
            app.DropDown_k = uidropdown(app.UIFigure);
            app.DropDown_k.Items = {'linear', 'log'};
            app.DropDown_k.Value = 'linear';
            app.DropDown_m = uidropdown(app.UIFigure);
            app.DropDown_m.Items = {'linear', 'log'};
            app.DropDown_m.Value = 'linear';
            app.ultimateconversionLabel = uilabel(app.UIFigure);
            app.ultimateconversionLabel.Text = 'ultimate conversion';
            app.rateconstantLabel = uilabel(app.UIFigure);
            app.rateconstantLabel.Text = 'rate constant';
            app.reactionorderLabel = uilabel(app.UIFigure);
            app.reactionorderLabel.Text = 'reaction order';
            app.ultimateconversionmin = uieditfield(app.UIFigure, 'numeric');
            app.ultimateconversionmax = uieditfield(app.UIFigure, 'numeric');
            app.ultimateconversionstep = uieditfield(app.UIFigure, 'numeric');
            app.rateconstantmin = uieditfield(app.UIFigure, 'numeric');
            app.rateconstantmax = uieditfield(app.UIFigure, 'numeric');
            app.rateconstantstep = uieditfield(app.UIFigure, 'numeric');
            app.reactionordermin = uieditfield(app.UIFigure, 'numeric');
            app.reactionordermax = uieditfield(app.UIFigure, 'numeric');
            app.reactionorderstep = uieditfield(app.UIFigure, 'numeric');
            app.selectdataButton = uibutton(app.UIFigure, 'push');
            app.selectdataButton.ButtonPushedFcn = createCallbackFcn(app, @selectdataButtonPushed, true);
            app.selectdataButton.Text = 'select data';
            app.filepath = uieditfield(app.UIFigure, 'text');
            app.fitButton = uibutton(app.UIFigure, 'push');
            app.fitButton.ButtonPushedFcn = createCallbackFcn(app, @fitButtonPushed, true);
            app.fitButton.Text = 'fit';
            app.filename = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fitGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end